classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Menu                  matlab.ui.container.Menu
        ToolsMenu             matlab.ui.container.Menu
        MotionControllerMenu  matlab.ui.container.Menu
        QuitMenu              matlab.ui.container.Menu
        Button                matlab.ui.control.Button
    end


    properties (Access = private)
        MotionControllerApp % Reference to MotionControllerApp dialogue
    end

    properties (Access = public)
        MotionControlLogger % Public so other windows can access it.
        MotionController
    end

    methods (Access = private)
        function newMotionControlLogEvent(app, msg, logs)
            %
            % TODO: There is a known bug where if you open, then close,
            % then open the motion controller app, an error occurs related
            % to logging callbacks. This is supposed to solve it, but
            % doesn't. Checking isvalid() or isobject() doesn't work either.
            % And if I set app.MotionControllerApp to something like false
            % after it's deleted, I get other errors.
            % What's actually going wrong?
            %
            if ~isempty(app.MotionControllerApp)
                app.MotionControllerApp.onLogEvent(msg, logs);
            end
        end
    end

    methods (Access = public)
        function closingMotionControlApp(app)
            % called from MotionControlApp when it's being closed
            app.MotionControllerMenu.Enable = 'on';
            delete(app.MotionControllerApp);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set up logging for motion controller
            app.MotionControlLogger = Logger();
            app.MotionControlLogger.SetCallback(@(m, l) app.newMotionControlLogEvent(m, l));

            % Set up motion controller
            app.MotionController = MotionController.MI4190_Prologix("/dev/ttyUSB0", 4, [1 2 4], app.MotionControlLogger)
            %app.MotionController = MotionController.Dummy([1 2 3], app.MotionControlLogger);
        end

        % Menu selected function: MotionControllerMenu
        function MotionControllerMenuSelected(app, event)
            app.MotionControllerApp = MotionControllerApp(app);
            app.MotionControllerMenu.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close all subwindows, then the main app.
            delete(app.MotionControllerApp)
            delete(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.MotionControlLogger.Info("asdfadsf");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ToolsMenu = uimenu(app.Menu);
            app.ToolsMenu.Text = 'Tools';
            app.MotionControllerMenu = uimenu(app.ToolsMenu);
            app.MotionControllerMenu.MenuSelectedFcn = createCallbackFcn(app, @MotionControllerMenuSelected, true);
            app.MotionControllerMenu.Text = 'Motion Controller';
            app.QuitMenu = uimenu(app.Menu);
            app.QuitMenu.Text = 'Quit';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end